classdef CovidApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID19DataVisualizationUIFigure  matlab.ui.Figure
        AverageofdaysSlider    matlab.ui.control.Slider
        AverageofdaysLabel     matlab.ui.control.Label
        OptionButtonGroup      matlab.ui.container.ButtonGroup
        DailyButton            matlab.ui.control.RadioButton
        CumulativeButton       matlab.ui.control.RadioButton
        DatatoplotButtonGroup  matlab.ui.container.ButtonGroup
        BothButton             matlab.ui.control.RadioButton
        DeathsButton           matlab.ui.control.RadioButton
        CasesButton            matlab.ui.control.RadioButton
        StatesorRegionListBox  matlab.ui.control.ListBox
        StatesorRegionLabel    matlab.ui.control.Label
        ListBox                matlab.ui.control.ListBox
        ListBoxLabel           matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
        ContextMenu            matlab.ui.container.ContextMenu
        Menu                   matlab.ui.container.Menu
        Menu2                  matlab.ui.container.Menu
    end

    
    properties (Access = private)
       countries_items % is a class have the Global date(date of country,injured, deaths ....)
       country_value % get the value of chosen country
       states_value
       country_index % get the index of the country in list 
       state_index
       % this fives properties store logical value depend on the chosen button
       showCases
       showDeaths
       showDaily
       showCumulative
       showBoth
       avgwind                 % take the slider value 
       Cases_Data              % store daily or comulative Cases data depend on the user's choice 
       Deaths_Data             % store daily or comulative deaths data depend on the user's choice 
       plot_title
       t_option                % t_option for daily of cumulative text in plot title 
       t_data                  % T_data  death or cases text in plot title 
       mean_title 
    end
    
    methods (Access = private)
        
        function plotData(app)
            cla(app.UIAxes, 'reset');

            s =datetime(app.countries_items.startDate);
            f = datetime(app.countries_items.endDate);
            days = daysact(s,f);  % get the number of days between start and end date
            xdate = datetime(2020,1,22:(22+days)); % vecotr for x axis
            app.Cases_Data = app.countries_items.countries{app.country_index}.statesOfCountry{app.state_index}; % acces to the country or states by index_countrry and state_index
            app.Deaths_Data =  app.Cases_Data;
              
                if app.showDaily 
                app.Cases_Data = app.Cases_Data.dailyInjured; % store daily injured 
                app.Deaths_Data = app.Deaths_Data.dailyDead; % store daily deaths
                app.t_option = 'Daily';
                elseif app.showCumulative
                 app.Cases_Data = app.Cases_Data.comulativeInjured;
                 app.Deaths_Data = app.Deaths_Data.comulativeDead;
                 app.t_option = 'Cumulative';
                end
                 if app.showCases
                app.t_data = 'Cases';
                app.calcAverage();
                bar(app.UIAxes,xdate,app.Cases_Data,'FaceColor', '#127bc1');
                ylim(app.UIAxes,[0 ceil(max(app.Cases_Data)*1.1)]);
                grid(app.UIAxes,'on');
                hold(app.UIAxes,"on");
                ylabel(app.UIAxes,'Number of Case')
                 end
               if app.showDeaths
                app.t_data = 'Deaths';
                app.calcAverage();
                if app.showBoth
                yyaxis(app.UIAxes,'right'); 
                app.t_data = 'Cases and Deaths';
                end
                plot(app.UIAxes,xdate,app.Deaths_Data,'r','LineWidth',1.2);
                ylim(app.UIAxes,[0 ceil(max(app.Deaths_Data)*1.1)]);
                hold(app.UIAxes,"on");
                ylabel(app.UIAxes,'Number of Deaths')
                grid(app.UIAxes,'on');
               end
              xlabel(app.UIAxes,'Date')
              hold(app.UIAxes,"off");
              app.change_title();
              app.UIAxes.Title.String = app.plot_title;
        end
            function calcAverage(app) % calcultate the mean day
                 if app.avgwind > 1
                    app.Cases_Data = movmean(app.Cases_Data,app.avgwind);
                    app.Deaths_Data = movmean(app.Deaths_Data,app.avgwind);  
                    app.mean_title = sprintf('(%d-day mean)',app.avgwind);
                 else
                    app.mean_title = '';
                 end
            end
            function change_title(app)
                % t_option for daily of cumulative text in plot title 
                % T_data  death or cases text in plot title 
                    if isequal(app.states_value,'All') % when 'All' is chosen we display the country name 
                        app.plot_title = sprintf('%s Number of %s in %s %s',app.t_option ,app.t_data,app.country_value,app.mean_title);                    
                    else
                       app.plot_title = sprintf('%s Number of %s in %s %s', app.t_option ,app.t_data,app.states_value,app.mean_title);
                    end
               end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
      app.countries_items = Global;
      app.ListBox.Items = app.countries_items.countriesName;
      app.state_index = 1;
      app.country_index =1;
      app.showCases =app.CasesButton.Value;
      app.showBoth = app.BothButton.Value;
      app.showCases =app.CasesButton.Value || app.BothButton.Value;
      app.showDeaths = app.DeathsButton.Value || app.BothButton.Value;
      app.showCumulative = app.CumulativeButton.Value;
      app.StatesorRegionListBox.Items = {'All'};
      app.states_value = 'All';
      app.country_value ='Global';
      app.avgwind = app.AverageofdaysSlider.Value;
      app.plotData();
     
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.country_value = app.ListBox.Value;
            app.country_index = find(strcmp(app.countries_items.countriesName,app.country_value));
            app.StatesorRegionListBox.Items = app.countries_items.countries{app.country_index}.statesName;
            app.StatesorRegionListBox.Value = app.StatesorRegionListBox.Items{1}; 
            app.state_index = 1;
            app.plotData();
               
        end

        % Value changed function: StatesorRegionListBox
        function StatesorRegionListBoxValueChanged(app, event)
            app.states_value = app.StatesorRegionListBox.Value;
            app.state_index = find(strcmp(app.countries_items.countries{app.country_index}.statesName,app.states_value));
            app.plotData();
            
        end

        % Selection changed function: DatatoplotButtonGroup
        function DatatoplotButtonGroupSelectionChanged(app, event)
            app.showCases =app.CasesButton.Value || app.BothButton.Value;
            app.showDeaths = app.DeathsButton.Value || app.BothButton.Value;
            app.showBoth = app.BothButton.Value;
            app.plotData();
            
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.showDaily = app.DailyButton.Value;
            app.showCumulative = app.CumulativeButton.Value;
            app.plotData();
            
        end

        % Value changing function: AverageofdaysSlider
        function AverageofdaysSliderValueChanging(app, event)
           app.avgwind = event.Value;
          app.avgwind = round(app.avgwind);
          app.AverageofdaysSlider.Value =  app.avgwind;
           app.plotData();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID19DataVisualizationUIFigure = uifigure('Visible', 'off');
            app.COVID19DataVisualizationUIFigure.Name = 'COVID-19 Data Visualization (Data Source: Johns Hopkins University)';
            app.UIAxes = uiaxes(app.COVID19DataVisualizationUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ListBoxLabel = uilabel(app.COVID19DataVisualizationUIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.COVID19DataVisualizationUIFigure);
            app.ListBox.Items = {'option 1', 'option 2', 'option 3'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'option 1';
            app.StatesorRegionLabel = uilabel(app.COVID19DataVisualizationUIFigure);
            app.StatesorRegionLabel.Text = {'States or'; 'Region:'};
            app.StatesorRegionListBox = uilistbox(app.COVID19DataVisualizationUIFigure);
            app.StatesorRegionListBox.Items = {'All'};
            app.StatesorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StatesorRegionListBoxValueChanged, true);
            app.StatesorRegionListBox.Value = 'All';
            app.DatatoplotButtonGroup = uibuttongroup(app.COVID19DataVisualizationUIFigure);
            app.DatatoplotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoplotButtonGroupSelectionChanged, true);
            app.DatatoplotButtonGroup.Title = 'Data to plot';
            app.CasesButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.COVID19DataVisualizationUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.AverageofdaysLabel = uilabel(app.COVID19DataVisualizationUIFigure);
            app.AverageofdaysLabel.Text = {'Average'; '# '; 'of days'};
            app.AverageofdaysSlider = uislider(app.COVID19DataVisualizationUIFigure);
            app.AverageofdaysSlider.Limits = [1 15];
            app.AverageofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AverageofdaysSliderValueChanging, true);
            app.AverageofdaysSlider.Value = 1;
            app.ContextMenu = uicontextmenu(app.COVID19DataVisualizationUIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.ListBox.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.COVID19DataVisualizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CovidApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID19DataVisualizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID19DataVisualizationUIFigure)
        end
    end
end